classdef LTO_tunner_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LogDebugPanel                   matlab.ui.container.Panel
        LogDebugGrid                    matlab.ui.container.GridLayout
        ConsoleoutputTextArea           matlab.ui.control.TextArea
        ConsoleoutputTextAreaLabel      matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        ResultsGrid                     matlab.ui.container.GridLayout
        SummarytablePanel               matlab.ui.container.Panel
        SummaryTableGrid                matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        PlotareaPanel                   matlab.ui.container.Panel
        PlotAreaGrid                    matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        PlotcontrolsPanel               matlab.ui.container.Panel
        PlotControlsGrid                matlab.ui.container.GridLayout
        PlotmodeselectionDropDown       matlab.ui.control.DropDown
        GenerateselectedplotButton      matlab.ui.control.Button
        PlottypeselectionDropDown       matlab.ui.control.DropDown
        PlottypeselectionDropDownLabel  matlab.ui.control.Label
        ParametersconfigurationPanel    matlab.ui.container.Panel
        LeftPanelGrid                   matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        LeftTabGroup                    matlab.ui.container.Tab
        VehicleGrid                     matlab.ui.container.GridLayout
        VehicleAeroPanel                matlab.ui.container.Panel
        AreoGrid                        matlab.ui.container.GridLayout
        RollingresistancecoefficientCrLabel  matlab.ui.control.Label
        ReardownforcecoefficientCl2Label  matlab.ui.control.Label
        FrontdownforcecoefficientCl1Label  matlab.ui.control.Label
        DragcoefficientCdLabel          matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Ns2m2Label_3                    matlab.ui.control.Label
        Ns2m2Label_2                    matlab.ui.control.Label
        Ns2m2Label                      matlab.ui.control.Label
        RollingResistanceEditField      matlab.ui.control.NumericEditField
        RearDownforceCoefficeintEditField  matlab.ui.control.NumericEditField
        FrontDownforceCoefficientEditField  matlab.ui.control.NumericEditField
        DragCoefficentEditField         matlab.ui.control.NumericEditField
        VehicleGeometryPanel            matlab.ui.container.Panel
        VehicleGeometryGrid             matlab.ui.container.GridLayout
        EditField3                      matlab.ui.control.NumericEditField
        LrEditField                     matlab.ui.control.NumericEditField
        LfEditField                     matlab.ui.control.NumericEditField
        WheelbaseEditField              matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        CGHeightUnitLabel               matlab.ui.control.Label
        LrUnitLabel                     matlab.ui.control.Label
        LfUnitLabel                     matlab.ui.control.Label
        CGHeightLabel                   matlab.ui.control.Label
        LrLabel                         matlab.ui.control.Label
        LfLabel                         matlab.ui.control.Label
        WheelbaseUnitLabel              matlab.ui.control.Label
        WheelbaseLabel                  matlab.ui.control.Label
        InertiaPanel                    matlab.ui.container.Panel
        InertiaGrid                     matlab.ui.container.GridLayout
        PitchInertiaEditField           matlab.ui.control.NumericEditField
        RollInertiaEditField            matlab.ui.control.NumericEditField
        YawInertiaEditField             matlab.ui.control.NumericEditField
        kgm2Label_3                     matlab.ui.control.Label
        kgm2Label_2                     matlab.ui.control.Label
        kgm2Label                       matlab.ui.control.Label
        PitchInertiaLabel               matlab.ui.control.Label
        RollInertiaLabel                matlab.ui.control.Label
        YawInertiaLabel                 matlab.ui.control.Label
        VehicleMassPanel                matlab.ui.container.Panel
        VehicleMassGrid                 matlab.ui.container.GridLayout
        RearTrackUnitLabel              matlab.ui.control.Label
        FrontTrackUnitLabel             matlab.ui.control.Label
        MassUnitLabel                   matlab.ui.control.Label
        RearTrackLabel                  matlab.ui.control.Label
        FrontTrackLabel                 matlab.ui.control.Label
        MassLabel                       matlab.ui.control.Label
        RearTrackEditField              matlab.ui.control.NumericEditField
        FrontTrackEditField             matlab.ui.control.NumericEditField
        MassEditField                   matlab.ui.control.NumericEditField
        TireTab                         matlab.ui.container.Tab
        TireGrid                        matlab.ui.container.GridLayout
        MF52alikelongitudalpeakPanel    matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        pDx1EditField                   matlab.ui.control.NumericEditField
        pCx1EditField                   matlab.ui.control.NumericEditField
        pDx2EditField                   matlab.ui.control.NumericEditField
        LambdaxEditField                matlab.ui.control.NumericEditField
        Label_30                        matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        pDx1Label                       matlab.ui.control.Label
        pCx1Label                       matlab.ui.control.Label
        Label_28                        matlab.ui.control.Label
        pDx2Label                       matlab.ui.control.Label
        lambda_xLabel                   matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        MF52alikelateralPanel           matlab.ui.container.Panel
        MF52LateralGrid                 matlab.ui.container.GridLayout
        pKy2Label                       matlab.ui.control.Label
        pKy4Label                       matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        pKy4_EditField                  matlab.ui.control.NumericEditField
        pKy2_EditField                  matlab.ui.control.NumericEditField
        pKy1_EditField                  matlab.ui.control.NumericEditField
        pEy2_EditField                  matlab.ui.control.NumericEditField
        pDy_2_EditField                 matlab.ui.control.NumericEditField
        lambday_EditField               matlab.ui.control.NumericEditField
        pEy3_EditField                  matlab.ui.control.NumericEditField
        pEy1_EditField                  matlab.ui.control.NumericEditField
        pDy1_EditField                  matlab.ui.control.NumericEditField
        pCy1_EditField                  matlab.ui.control.NumericEditField
        pKy1Label                       matlab.ui.control.Label
        pEy2Label                       matlab.ui.control.Label
        pDy2Label                       matlab.ui.control.Label
        lambda_yLabel                   matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        pEy3Label                       matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        pEy1Label                       matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        pDy1Label                       matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        pCy1Label_2                     matlab.ui.control.Label
        sMFlateralparametsPanel         matlab.ui.container.Panel
        sMFLateralGrid                  matlab.ui.container.GridLayout
        BLabel                          matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        DLabel                          matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        BEditField                      matlab.ui.control.NumericEditField
        EditField_6Label                matlab.ui.control.Label
        CEditField                      matlab.ui.control.NumericEditField
        EditField_7Label                matlab.ui.control.Label
        DEditField                      matlab.ui.control.NumericEditField
        EditField_5Label                matlab.ui.control.Label
        GeneralPanel                    matlab.ui.container.Panel
        GeneralGrid                     matlab.ui.container.GridLayout
        NominalLoadEditField            matlab.ui.control.NumericEditField
        TireRadiusEditField             matlab.ui.control.NumericEditField
        RelaxationLengthEditField       matlab.ui.control.NumericEditField
        mLabel_2                        matlab.ui.control.Label
        RelaxationlengthlateralLabel    matlab.ui.control.Label
        TireradiusLabel                 matlab.ui.control.Label
        NominalloadLabel                matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        SuspensionTab                   matlab.ui.container.Tab
        SuspensionGrid                  matlab.ui.container.GridLayout
        AntigeometryPanel               matlab.ui.container.Panel
        AntiGeometryGrid                matlab.ui.container.GridLayout
        AntiSquatASLabel                matlab.ui.control.Label
        AntiDiveADLabel                 matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        AntiSquatEditField              matlab.ui.control.NumericEditField
        AntiDiveEditField               matlab.ui.control.NumericEditField
        SpringdamperdataPanel           matlab.ui.container.Panel
        SpringDamperGrid                matlab.ui.container.GridLayout
        DamingRatioLongitudalEditField  matlab.ui.control.NumericEditField
        SprungMassFrontEditField        matlab.ui.control.NumericEditField
        DampingRatioLateralEditField    matlab.ui.control.NumericEditField
        SprungMassRearEditField         matlab.ui.control.NumericEditField
        ARBRearEditField                matlab.ui.control.NumericEditField
        ARBFrontEditField               matlab.ui.control.NumericEditField
        WhellRateRearEditField          matlab.ui.control.NumericEditField
        WhellRateFrontEditField         matlab.ui.control.NumericEditField
        AntirollbarstiffnesfrontK_ARB_fLabel  matlab.ui.control.Label
        Whellratefrontkw_fLabel         matlab.ui.control.Label
        AntirollbarstiffnesrearK_ARB_rLabel  matlab.ui.control.Label
        Whellraterearkw_rLabel          matlab.ui.control.Label
        Dampingratiolongitudalpitchzeta_thetaLabel  matlab.ui.control.Label
        Dampingratiolateralrollzeta_phiLabel  matlab.ui.control.Label
        SprungmassrearLabel             matlab.ui.control.Label
        SprungmassfrontLabel            matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        NmradLabel_2                    matlab.ui.control.Label
        NmradLabel                      matlab.ui.control.Label
        NmLabel_2                       matlab.ui.control.Label
        NmLabel                         matlab.ui.control.Label
        RollgeometryPanel               matlab.ui.container.Panel
        RollGeometryGrid                matlab.ui.container.GridLayout
        HRollRearEditField              matlab.ui.control.NumericEditField
        HRollFrontEditField             matlab.ui.control.NumericEditField
        mLabel_4                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        Heightofrollcenterrearh_RCrLabel  matlab.ui.control.Label
        Heightofrollcenterfronth_RCfLabel  matlab.ui.control.Label
        DrivetrainTab                   matlab.ui.container.Tab
        DrivetrainGrid                  matlab.ui.container.GridLayout
        DrivetrainPanel                 matlab.ui.container.Panel
        DrivetrainPanelGrid             matlab.ui.container.GridLayout
        FirstordertimeconstantLabel     matlab.ui.control.Label
        FirstOrderTimeConstantEditField  matlab.ui.control.NumericEditField
        MaxDrivePowerEditField          matlab.ui.control.NumericEditField
        MaxBrakePowerEditField          matlab.ui.control.NumericEditField
        MaxDriveTorqueEditField         matlab.ui.control.NumericEditField
        MaxBrakeTorqueEditField         matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        NmLabel_4                       matlab.ui.control.Label
        NmLabel_3                       matlab.ui.control.Label
        kWLabel_2                       matlab.ui.control.Label
        kWLabel                         matlab.ui.control.Label
        MaximaldrivetorqueLabel         matlab.ui.control.Label
        MaximalbrakerecuperationtorqueLabel  matlab.ui.control.Label
        MaximaldrivepowerLabel          matlab.ui.control.Label
        MaximalbrakerecuperationpowerLabel  matlab.ui.control.Label
        SteeringSystem                  matlab.ui.container.Tab
        SteeringGrid                    matlab.ui.container.GridLayout
        SteeringPanel                   matlab.ui.container.Panel
        SteeringPanelGrid               matlab.ui.container.GridLayout
        MaxSteerignAngleEditField       matlab.ui.control.NumericEditField
        radLabel                        matlab.ui.control.Label
        MaxsteeringangleLabel           matlab.ui.control.Label
        DampingratioIIordermodelLabel   matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        radsLabel                       matlab.ui.control.Label
        NaturalfreuancyIIordermodelLabel  matlab.ui.control.Label
        DampingRatioEditField           matlab.ui.control.NumericEditField
        NaturalFrequancyEditField       matlab.ui.control.NumericEditField
        SolverTab                       matlab.ui.container.Tab
        SolverGrid                      matlab.ui.container.GridLayout
        IPOPTsettingsPanel              matlab.ui.container.Panel
        IPOPTSettingsPanelGrid          matlab.ui.container.GridLayout
        AcceptableTolEditField          matlab.ui.control.NumericEditField
        PrintLevelEditField             matlab.ui.control.NumericEditField
        MaxIterationsEditField          matlab.ui.control.NumericEditField
        TolFinalEditField               matlab.ui.control.NumericEditField
        TolPreviewEditField             matlab.ui.control.NumericEditField
        TolDebugEditField               matlab.ui.control.NumericEditField
        AcceptabletolLabel              matlab.ui.control.Label
        PrintlevelLabel                 matlab.ui.control.Label
        MaxiterationsLabel              matlab.ui.control.Label
        TolfinalLabel                   matlab.ui.control.Label
        TolpreviewLabel                 matlab.ui.control.Label
        ToldebugLabel                   matlab.ui.control.Label
        InitialguessbackwardforwardsettingsPanel  matlab.ui.container.Panel
        InitialGuessGrid                matlab.ui.container.GridLayout
        SafetyFactoEditField            matlab.ui.control.NumericEditField
        MaximalSpeedEditField           matlab.ui.control.NumericEditField
        InitalSpeedEditField            matlab.ui.control.NumericEditField
        InitalspeedforBFLabel           matlab.ui.control.Label
        MaximalspeedforBFLabel          matlab.ui.control.Label
        SafetyfactorforBFLabel          matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        DiscretizationPanel             matlab.ui.container.Panel
        DiscretizationGrid              matlab.ui.container.GridLayout
        SubstepsofIntegrationEditField  matlab.ui.control.NumericEditField
        SubstepsofintegrationLabel      matlab.ui.control.Label
        IntegratorLabel                 matlab.ui.control.Label
        IntegratorDropDown              matlab.ui.control.DropDown
        FinalNumberPointsEditField      matlab.ui.control.NumericEditField
        PrewievNumberPointsEditField    matlab.ui.control.NumericEditField
        DebugNumberPointsEditField      matlab.ui.control.NumericEditField
        FinalnumberofpointsLabel        matlab.ui.control.Label
        PreviewnumberofpointsLabel      matlab.ui.control.Label
        DebugnumberofpointsLabel        matlab.ui.control.Label
        SolverModePanel                 matlab.ui.container.Panel
        SolverModeGrid                  matlab.ui.container.GridLayout
        InitialguessstrategyDropDown    matlab.ui.control.DropDown
        InitialguessstrategyDropDownLabel  matlab.ui.control.Label
        LTOModeDropDown                 matlab.ui.control.DropDown
        BackendmodelDropDownLabel       matlab.ui.control.Label
        SolverLevelDropDown             matlab.ui.control.DropDown
        SolvelevelDropDownLabel         matlab.ui.control.Label
        BoundsTab                       matlab.ui.container.Tab
        BoundsGrid                      matlab.ui.container.GridLayout
        StateboundsPanel                matlab.ui.container.Panel
        StateBoundsGrid                 matlab.ui.container.GridLayout
        TrackwidithutilizationmodeLabel  matlab.ui.control.Label
        TrackWidithDropDown             matlab.ui.control.DropDown
        MinimalTrackMarginEditField     matlab.ui.control.NumericEditField
        MinimalSpeedBoundEditField      matlab.ui.control.NumericEditField
        MaximalSpeedBoundEditField      matlab.ui.control.NumericEditField
        BetaAngleEditField              matlab.ui.control.NumericEditField
        MaximalbetaangleLabel           matlab.ui.control.Label
        MaximalspeedLabel               matlab.ui.control.Label
        MinimalspeedLabel               matlab.ui.control.Label
        MinimaltrackmarginLabel         matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        radLabel_2                      matlab.ui.control.Label
        ControlratesboundsPanel         matlab.ui.container.Panel
        ControlBoundsGrid               matlab.ui.container.GridLayout
        MaxTorqueCommandRateEditField   matlab.ui.control.NumericEditField
        MaxSteeringCommandRateEditField  matlab.ui.control.NumericEditField
        Label_26                        matlab.ui.control.Label
        rads2Label                      matlab.ui.control.Label
        MaxtorquecommandratenormalizedLabel  matlab.ui.control.Label
        MaxsteeringcomandrateLabel      matlab.ui.control.Label
        CostTab                         matlab.ui.container.Tab
        CostGrid                        matlab.ui.container.GridLayout
        SlackscostPanel                 matlab.ui.container.Panel
        SlacksCostGrid                  matlab.ui.container.GridLayout
        TrackviolationslackcostLabel    matlab.ui.control.Label
        FrictionelipseslackcostLabel    matlab.ui.control.Label
        PowerslackcostLabel             matlab.ui.control.Label
        TrackViolationSlackCostEditField  matlab.ui.control.NumericEditField
        FrictionElipseSlackCostEditField  matlab.ui.control.NumericEditField
        PowerSlackCostEditField         matlab.ui.control.NumericEditField
        ControlcostPanel                matlab.ui.container.Panel
        ControlCostGrid                 matlab.ui.container.GridLayout
        TorqueratecostLabel             matlab.ui.control.Label
        SteerratecostLabel              matlab.ui.control.Label
        TorqueRateCostEditField         matlab.ui.control.NumericEditField
        SteerRateCostEditField          matlab.ui.control.NumericEditField
        StatecostPanel                  matlab.ui.container.Panel
        StateCostGrid                   matlab.ui.container.GridLayout
        SpeedcostLabel                  matlab.ui.control.Label
        BetacostLabel                   matlab.ui.control.Label
        SpeedCostEditField              matlab.ui.control.NumericEditField
        BetaCostEditField               matlab.ui.control.NumericEditField
        FilesTab                        matlab.ui.container.Tab
        FilesGrid                       matlab.ui.container.GridLayout
        OutputPanel                     matlab.ui.container.Panel
        OutputGrid                      matlab.ui.container.GridLayout
        SettingsofoutputexportDropDown  matlab.ui.control.DropDown
        SettingsofoutputexportDropDownLabel  matlab.ui.control.Label
        BrowseforfolderandexportButton  matlab.ui.control.Button
        ExportNLPsolutiontoMATButton    matlab.ui.control.Button
        ExportNLPsolutiontoCSVButton    matlab.ui.control.Button
        OutputfolderpathEditField       matlab.ui.control.EditField
        EditField_6Label_5              matlab.ui.control.Label
        InitialGuessPreloadPanel        matlab.ui.container.Panel
        InitallGuessPrelodGrid          matlab.ui.container.GridLayout
        SaveinitialguesstofileButton    matlab.ui.control.Button
        BrowseandloadinitalguessfromfileButton  matlab.ui.control.Button
        InitialguesspathEditField       matlab.ui.control.EditField
        EditField_6Label_4              matlab.ui.control.Label
        ConfigPanel                     matlab.ui.container.Panel
        ConfigGrid                      matlab.ui.container.GridLayout
        ConfignotloadedyetLabel         matlab.ui.control.Label
        BrowseconfigurationfilestoloadButton  matlab.ui.control.Button
        LoadconfigfromfileButton        matlab.ui.control.Button
        SaveconfigtofileButton          matlab.ui.control.Button
        ConfigurationfilepathEditField  matlab.ui.control.EditField
        EditField_6Label_3              matlab.ui.control.Label
        TrackfilePanel                  matlab.ui.container.Panel
        TrackFileGrid                   matlab.ui.container.GridLayout
        IstrackclosedperiodicDropDown   matlab.ui.control.DropDown
        IstrackclosedperiodicDropDownLabel  matlab.ui.control.Label
        TracknotloadedyetLabel          matlab.ui.control.Label
        BrowseandloadtrackfromfileButton  matlab.ui.control.Button
        PreviewTrackButton              matlab.ui.control.Button
        TrackfilepathEditField          matlab.ui.control.EditField
        EditField_6Label_2              matlab.ui.control.Label
        TrackfileformatRequiredCSVx_left_my_left_mx_center_my_center_mx_right_my_right_mLabel  matlab.ui.control.Label
        GeneralPanel_2                  matlab.ui.container.Panel
        TopBarGrid                      matlab.ui.container.GridLayout
        StatusLabel                     matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        SolveButton                     matlab.ui.control.Button
        SolveLevelDropDown              matlab.ui.control.DropDown
        DropDown2Label_2                matlab.ui.control.Label
        VehicleModelDropDown            matlab.ui.control.DropDown
        DropDown2Label                  matlab.ui.control.Label
        ResetDefaultsButton             matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        LoadTrackButton                 matlab.ui.control.Button
        LoadConfigButton                matlab.ui.control.Button
        AppTitleLabel                   matlab.ui.control.Label
    end

    
    % global cache of runtime containers 
    properties (Access = public)
        Config
        Track
        InitialGuess
        LastSolution
        ProjectRoot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseconfigurationfilestoloadButton
        function BrowseconfigurationfilestoloadButtonPushed(app, event)
                lto.callbacks.browseConfigFile(app);
        end

        % Button pushed function: LoadconfigfromfileButton
        function LoadconfigfromfileButtonPushed(app, event)
                lto.callbacks.loadConfigFromField(app);
        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
               lto.callbacks.browseConfigFile(app);
               lto.callbacks.loadConfigFromField(app);
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
             lto.callbacks.saveConfigToFile(app);
        end

        % Button pushed function: SaveconfigtofileButton
        function SaveconfigtofileButtonPushed(app, event)
             lto.callbacks.saveConfigToFile(app);
        end

        % Button pushed function: LoadTrackButton
        function LoadTrackButtonPushed(app, event)
                lto.callbacks.loadTrackWithBrowse(app);
        end

        % Button pushed function: BrowseandloadtrackfromfileButton
        function BrowseandloadtrackfromfileButtonPushed(app, event)
                lto.callbacks.browseTrackFile(app);
        end

        % Button pushed function: PreviewTrackButton
        function PreviewTrackButtonPushed(app, event)
                lto.callbacks.previewTrack(app);
        end

        % Value changed function: VehicleModelDropDown
        function VehicleModelDropDownValueChanged(app, event)
            lto.callbacks.syncModelFromTopBar(app);
        end

        % Value changed function: LTOModeDropDown
        function LTOModeDropDownValueChanged(app, event)
                 lto.callbacks.syncModelFromSolver(app);
        end

        % Value changed function: SolverLevelDropDown
        function SolverLevelDropDownValueChanged(app, event)
                 lto.callbacks.syncSolveLevelFromSolver(app);
        end

        % Value changed function: SolveLevelDropDown
        function SolveLevelDropDownValueChanged(app, event)
                 lto.callbacks.syncSolveLevelFromTopBar(app);
        end

        % Button pushed function: ResetDefaultsButton
        function ResetDefaultsButtonPushed(app, event)
                lto.callbacks.resetDefaultConfig(app);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
                lto.callbacks.solveProblem(app);
        end

        % Button pushed function: GenerateselectedplotButton
        function GenerateselectedplotButtonPushed(app, event)
                lto.callbacks.generateSelectedPlot(app);
        end

        % Button pushed function: SaveinitialguesstofileButton
        function SaveinitialguesstofileButtonPushed(app, event)
                lto.callbacks.saveInitialGuessToFile(app);
        end

        % Button pushed function: BrowseandloadinitalguessfromfileButton
        function BrowseandloadinitalguessfromfileButtonPushed(app, event)
                lto.callbacks.loadInitialGuessFromFile(app);
        end

        % Button pushed function: ExportNLPsolutiontoMATButton
        function ExportNLPsolutiontoMATButtonPushed(app, event)
                lto.callbacks.exportLastSolutionToMat(app);
        end

        % Button pushed function: ExportNLPsolutiontoCSVButton
        function ExportNLPsolutiontoCSVButtonPushed(app, event)
                lto.callbacks.exportLastSolutionToCsvFolder(app);
        end

        % Button pushed function: BrowseforfolderandexportButton
        function BrowseforfolderandexportButtonPushed(app, event)
                lto.callbacks.browseAndExportOutputPackage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GeneralPanel_2 = uipanel(app.GridLayout);
            app.GeneralPanel_2.Title = 'General';
            app.TopBarGrid = uigridlayout(app.GeneralPanel_2);
            app.AppTitleLabel = uilabel(app.TopBarGrid);
            app.AppTitleLabel.Text = 'FS LTO Tuning App';
            app.LoadConfigButton = uibutton(app.TopBarGrid, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = {'Load config '; 'from file'};
            app.LoadTrackButton = uibutton(app.TopBarGrid, 'push');
            app.LoadTrackButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrackButtonPushed, true);
            app.LoadTrackButton.Text = {'Load track '; 'from file'};
            app.SaveConfigButton = uibutton(app.TopBarGrid, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = {'Save config'; 'to file'};
            app.ResetDefaultsButton = uibutton(app.TopBarGrid, 'push');
            app.ResetDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDefaultsButtonPushed, true);
            app.ResetDefaultsButton.Text = {'Reset'; 'to deafult'};
            app.DropDown2Label = uilabel(app.TopBarGrid);
            app.DropDown2Label.Text = 'Drop Down2';
            app.VehicleModelDropDown = uidropdown(app.TopBarGrid);
            app.VehicleModelDropDown.Items = {'Point mass', 'Dynamic bicycle', 'Quasi-static suspension ', 'Dynamic suspension '};
            app.VehicleModelDropDown.ValueChangedFcn = createCallbackFcn(app, @VehicleModelDropDownValueChanged, true);
            app.VehicleModelDropDown.Value = 'Point mass';
            app.DropDown2Label_2 = uilabel(app.TopBarGrid);
            app.DropDown2Label_2.Text = 'Drop Down2';
            app.SolveLevelDropDown = uidropdown(app.TopBarGrid);
            app.SolveLevelDropDown.Items = {'Preview', 'Debug', 'Final'};
            app.SolveLevelDropDown.ValueChangedFcn = createCallbackFcn(app, @SolveLevelDropDownValueChanged, true);
            app.SolveLevelDropDown.Value = 'Preview';
            app.SolveButton = uibutton(app.TopBarGrid, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.StatusLampLabel = uilabel(app.TopBarGrid);
            app.StatusLampLabel.Text = 'StatusLamp';
            app.StatusLamp = uilamp(app.TopBarGrid);
            app.StatusLabel = uilabel(app.TopBarGrid);
            app.StatusLabel.Text = 'Ready';
            app.ParametersconfigurationPanel = uipanel(app.GridLayout);
            app.ParametersconfigurationPanel.Title = 'Parameters configuration';
            app.LeftPanelGrid = uigridlayout(app.ParametersconfigurationPanel);
            app.TabGroup = uitabgroup(app.LeftPanelGrid);
            app.LeftTabGroup = uitab(app.TabGroup);
            app.LeftTabGroup.Title = 'Vehicle';
            app.VehicleGrid = uigridlayout(app.LeftTabGroup);
            app.VehicleMassPanel = uipanel(app.VehicleGrid);
            app.VehicleMassPanel.Title = 'Mass / Tracks';
            app.VehicleMassGrid = uigridlayout(app.VehicleMassPanel);
            app.MassEditField = uieditfield(app.VehicleMassGrid, 'numeric');
            app.MassEditField.Value = 195.5;
            app.FrontTrackEditField = uieditfield(app.VehicleMassGrid, 'numeric');
            app.FrontTrackEditField.Value = 1.2;
            app.RearTrackEditField = uieditfield(app.VehicleMassGrid, 'numeric');
            app.RearTrackEditField.Value = 1.17;
            app.MassLabel = uilabel(app.VehicleMassGrid);
            app.MassLabel.Text = 'Mass';
            app.FrontTrackLabel = uilabel(app.VehicleMassGrid);
            app.FrontTrackLabel.Text = 'Front track tf';
            app.RearTrackLabel = uilabel(app.VehicleMassGrid);
            app.RearTrackLabel.Text = 'Rear track tr';
            app.MassUnitLabel = uilabel(app.VehicleMassGrid);
            app.MassUnitLabel.Text = 'kg';
            app.FrontTrackUnitLabel = uilabel(app.VehicleMassGrid);
            app.FrontTrackUnitLabel.Text = 'm';
            app.RearTrackUnitLabel = uilabel(app.VehicleMassGrid);
            app.RearTrackUnitLabel.Text = 'm';
            app.InertiaPanel = uipanel(app.VehicleGrid);
            app.InertiaPanel.Title = 'Inertia';
            app.InertiaGrid = uigridlayout(app.InertiaPanel);
            app.YawInertiaLabel = uilabel(app.InertiaGrid);
            app.YawInertiaLabel.Text = 'Yaw Inertia Iz';
            app.RollInertiaLabel = uilabel(app.InertiaGrid);
            app.RollInertiaLabel.Text = 'Roll Inertia Iphi';
            app.PitchInertiaLabel = uilabel(app.InertiaGrid);
            app.PitchInertiaLabel.Text = 'Pitch Inertia Itheta';
            app.kgm2Label = uilabel(app.InertiaGrid);
            app.kgm2Label.Text = 'kgm^2';
            app.kgm2Label_2 = uilabel(app.InertiaGrid);
            app.kgm2Label_2.Text = 'kgm^2';
            app.kgm2Label_3 = uilabel(app.InertiaGrid);
            app.kgm2Label_3.Text = 'kgm^2';
            app.YawInertiaEditField = uieditfield(app.InertiaGrid, 'numeric');
            app.YawInertiaEditField.Value = 85;
            app.RollInertiaEditField = uieditfield(app.InertiaGrid, 'numeric');
            app.RollInertiaEditField.Value = 60;
            app.PitchInertiaEditField = uieditfield(app.InertiaGrid, 'numeric');
            app.PitchInertiaEditField.Value = 30;
            app.VehicleGeometryPanel = uipanel(app.VehicleGrid);
            app.VehicleGeometryPanel.Title = 'Geometry';
            app.VehicleGeometryGrid = uigridlayout(app.VehicleGeometryPanel);
            app.WheelbaseLabel = uilabel(app.VehicleGeometryGrid);
            app.WheelbaseLabel.Text = 'Wheelbase L';
            app.WheelbaseUnitLabel = uilabel(app.VehicleGeometryGrid);
            app.WheelbaseUnitLabel.Text = 'm';
            app.LfLabel = uilabel(app.VehicleGeometryGrid);
            app.LfLabel.Text = 'CG to front lf';
            app.LrLabel = uilabel(app.VehicleGeometryGrid);
            app.LrLabel.Text = 'CG to rear lr';
            app.CGHeightLabel = uilabel(app.VehicleGeometryGrid);
            app.CGHeightLabel.Text = 'CG height';
            app.LfUnitLabel = uilabel(app.VehicleGeometryGrid);
            app.LfUnitLabel.Text = 'm';
            app.LrUnitLabel = uilabel(app.VehicleGeometryGrid);
            app.LrUnitLabel.Text = 'm';
            app.CGHeightUnitLabel = uilabel(app.VehicleGeometryGrid);
            app.CGHeightUnitLabel.Text = 'm';
            app.Label = uilabel(app.VehicleGeometryGrid);
            app.Label.Text = '';
            app.WheelbaseEditField = uieditfield(app.VehicleGeometryGrid, 'numeric');
            app.WheelbaseEditField.Value = 1.53;
            app.LfEditField = uieditfield(app.VehicleGeometryGrid, 'numeric');
            app.LfEditField.Value = 0.904;
            app.LrEditField = uieditfield(app.VehicleGeometryGrid, 'numeric');
            app.LrEditField.Value = 0.626;
            app.EditField3 = uieditfield(app.VehicleGeometryGrid, 'numeric');
            app.EditField3.Value = 0.27;
            app.VehicleAeroPanel = uipanel(app.VehicleGrid);
            app.VehicleAeroPanel.Title = 'Aero / Resistance';
            app.AreoGrid = uigridlayout(app.VehicleAeroPanel);
            app.DragCoefficentEditField = uieditfield(app.AreoGrid, 'numeric');
            app.FrontDownforceCoefficientEditField = uieditfield(app.AreoGrid, 'numeric');
            app.RearDownforceCoefficeintEditField = uieditfield(app.AreoGrid, 'numeric');
            app.RollingResistanceEditField = uieditfield(app.AreoGrid, 'numeric');
            app.Ns2m2Label = uilabel(app.AreoGrid);
            app.Ns2m2Label.Text = 'Ns^2/m^2';
            app.Ns2m2Label_2 = uilabel(app.AreoGrid);
            app.Ns2m2Label_2.Text = 'Ns^2/m^2';
            app.Ns2m2Label_3 = uilabel(app.AreoGrid);
            app.Ns2m2Label_3.Text = 'Ns^2/m^2';
            app.Label_2 = uilabel(app.AreoGrid);
            app.Label_2.Text = ' -';
            app.DragcoefficientCdLabel = uilabel(app.AreoGrid);
            app.DragcoefficientCdLabel.Text = {'Drag coefficient Cd'; ''};
            app.FrontdownforcecoefficientCl1Label = uilabel(app.AreoGrid);
            app.FrontdownforcecoefficientCl1Label.Text = {'Front downforce coefficient Cl1'; ''};
            app.ReardownforcecoefficientCl2Label = uilabel(app.AreoGrid);
            app.ReardownforcecoefficientCl2Label.Text = {'Rear downforce coefficient Cl2'; ''};
            app.RollingresistancecoefficientCrLabel = uilabel(app.AreoGrid);
            app.RollingresistancecoefficientCrLabel.Text = 'Rolling resistance coefficient Cr';
            app.TireTab = uitab(app.TabGroup);
            app.TireTab.Title = 'Tire';
            app.TireGrid = uigridlayout(app.TireTab);
            app.GeneralPanel = uipanel(app.TireGrid);
            app.GeneralPanel.Title = 'General';
            app.GeneralGrid = uigridlayout(app.GeneralPanel);
            app.NLabel = uilabel(app.GeneralGrid);
            app.NLabel.Text = 'N';
            app.mLabel = uilabel(app.GeneralGrid);
            app.mLabel.Text = 'm';
            app.NominalloadLabel = uilabel(app.GeneralGrid);
            app.NominalloadLabel.Text = 'Nominal load';
            app.TireradiusLabel = uilabel(app.GeneralGrid);
            app.TireradiusLabel.Text = 'Tire radius';
            app.RelaxationlengthlateralLabel = uilabel(app.GeneralGrid);
            app.RelaxationlengthlateralLabel.Text = 'Relaxation length lateral';
            app.mLabel_2 = uilabel(app.GeneralGrid);
            app.mLabel_2.Text = 'm';
            app.RelaxationLengthEditField = uieditfield(app.GeneralGrid, 'numeric');
            app.TireRadiusEditField = uieditfield(app.GeneralGrid, 'numeric');
            app.NominalLoadEditField = uieditfield(app.GeneralGrid, 'numeric');
            app.sMFlateralparametsPanel = uipanel(app.TireGrid);
            app.sMFlateralparametsPanel.Title = 'sMF lateral paramets';
            app.sMFLateralGrid = uigridlayout(app.sMFlateralparametsPanel);
            app.EditField_5Label = uilabel(app.sMFLateralGrid);
            app.EditField_5Label.Text = '';
            app.DEditField = uieditfield(app.sMFLateralGrid, 'numeric');
            app.EditField_7Label = uilabel(app.sMFLateralGrid);
            app.EditField_7Label.Text = '';
            app.CEditField = uieditfield(app.sMFLateralGrid, 'numeric');
            app.EditField_6Label = uilabel(app.sMFLateralGrid);
            app.EditField_6Label.Text = '';
            app.BEditField = uieditfield(app.sMFLateralGrid, 'numeric');
            app.Label_4 = uilabel(app.sMFLateralGrid);
            app.Label_4.Text = '-';
            app.Label_5 = uilabel(app.sMFLateralGrid);
            app.Label_5.Text = '-';
            app.Label_6 = uilabel(app.sMFLateralGrid);
            app.Label_6.Text = '-';
            app.DLabel = uilabel(app.sMFLateralGrid);
            app.DLabel.Text = 'D';
            app.CLabel = uilabel(app.sMFLateralGrid);
            app.CLabel.Text = 'C';
            app.BLabel = uilabel(app.sMFLateralGrid);
            app.BLabel.Text = 'B';
            app.MF52alikelateralPanel = uipanel(app.TireGrid);
            app.MF52alikelateralPanel.Title = 'MF 5.2 a-like lateral ';
            app.MF52LateralGrid = uigridlayout(app.MF52alikelateralPanel);
            app.pCy1Label_2 = uilabel(app.MF52LateralGrid);
            app.pCy1Label_2.Text = 'pCy1';
            app.Label_11 = uilabel(app.MF52LateralGrid);
            app.Label_11.Text = '-';
            app.pDy1Label = uilabel(app.MF52LateralGrid);
            app.pDy1Label.Text = 'pDy1';
            app.Label_10 = uilabel(app.MF52LateralGrid);
            app.Label_10.Text = '-';
            app.pEy1Label = uilabel(app.MF52LateralGrid);
            app.pEy1Label.Text = 'pEy1';
            app.Label_9 = uilabel(app.MF52LateralGrid);
            app.Label_9.Text = '-';
            app.pEy3Label = uilabel(app.MF52LateralGrid);
            app.pEy3Label.Text = 'pEy3';
            app.Label_8 = uilabel(app.MF52LateralGrid);
            app.Label_8.Text = '-';
            app.Label_12 = uilabel(app.MF52LateralGrid);
            app.Label_12.Text = '-';
            app.Label_13 = uilabel(app.MF52LateralGrid);
            app.Label_13.Text = '-';
            app.Label_14 = uilabel(app.MF52LateralGrid);
            app.Label_14.Text = '-';
            app.Label_15 = uilabel(app.MF52LateralGrid);
            app.Label_15.Text = '-';
            app.lambda_yLabel = uilabel(app.MF52LateralGrid);
            app.lambda_yLabel.Text = 'lambda_y';
            app.pDy2Label = uilabel(app.MF52LateralGrid);
            app.pDy2Label.Text = 'pDy2';
            app.pEy2Label = uilabel(app.MF52LateralGrid);
            app.pEy2Label.Text = 'pEy2';
            app.pKy1Label = uilabel(app.MF52LateralGrid);
            app.pKy1Label.Text = 'pKy1';
            app.pCy1_EditField = uieditfield(app.MF52LateralGrid, 'numeric');
            app.pCy1_EditField.Value = 1.5;
            app.pDy1_EditField = uieditfield(app.MF52LateralGrid, 'numeric');
            app.pDy1_EditField.Value = 2.6319;
            app.pEy1_EditField = uieditfield(app.MF52LateralGrid, 'numeric');
            app.pEy1_EditField.Value = 0.5;
            app.pEy3_EditField = uieditfield(app.MF52LateralGrid, 'numeric');
            app.lambday_EditField = uieditfield(app.MF52LateralGrid, 'numeric');
            app.lambday_EditField.Value = 0.44;
            app.pDy_2_EditField = uieditfield(app.MF52LateralGrid, 'numeric');
            app.pDy_2_EditField.Value = -0.194;
            app.pEy2_EditField = uieditfield(app.MF52LateralGrid, 'numeric');
            app.pKy1_EditField = uieditfield(app.MF52LateralGrid, 'numeric');
            app.pKy1_EditField.Value = 59.1707;
            app.pKy2_EditField = uieditfield(app.MF52LateralGrid, 'numeric');
            app.pKy2_EditField.Value = 2.5329;
            app.pKy4_EditField = uieditfield(app.MF52LateralGrid, 'numeric');
            app.pKy4_EditField.Value = 2;
            app.Label_16 = uilabel(app.MF52LateralGrid);
            app.Label_16.Text = '-';
            app.Label_17 = uilabel(app.MF52LateralGrid);
            app.Label_17.Text = '-';
            app.pKy4Label = uilabel(app.MF52LateralGrid);
            app.pKy4Label.Text = 'pKy4';
            app.pKy2Label = uilabel(app.MF52LateralGrid);
            app.pKy2Label.Text = 'pKy2';
            app.MF52alikelongitudalpeakPanel = uipanel(app.TireGrid);
            app.MF52alikelongitudalpeakPanel.Title = 'MF 5.2 a-like longitudal peak';
            app.GridLayout2 = uigridlayout(app.MF52alikelongitudalpeakPanel);
            app.Label_27 = uilabel(app.GridLayout2);
            app.Label_27.Text = '-';
            app.lambda_xLabel = uilabel(app.GridLayout2);
            app.lambda_xLabel.Text = 'lambda_x';
            app.pDx2Label = uilabel(app.GridLayout2);
            app.pDx2Label.Text = 'pDx2';
            app.Label_28 = uilabel(app.GridLayout2);
            app.Label_28.Text = '-';
            app.pCx1Label = uilabel(app.GridLayout2);
            app.pCx1Label.Text = 'pCx1';
            app.pDx1Label = uilabel(app.GridLayout2);
            app.pDx1Label.Text = 'pDx1';
            app.Label_29 = uilabel(app.GridLayout2);
            app.Label_29.Text = '-';
            app.Label_30 = uilabel(app.GridLayout2);
            app.Label_30.Text = '-';
            app.LambdaxEditField = uieditfield(app.GridLayout2, 'numeric');
            app.pDx2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.pCx1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.pDx1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.SuspensionTab = uitab(app.TabGroup);
            app.SuspensionTab.Title = 'Suspension';
            app.SuspensionGrid = uigridlayout(app.SuspensionTab);
            app.RollgeometryPanel = uipanel(app.SuspensionGrid);
            app.RollgeometryPanel.Title = ' Roll geometry';
            app.RollGeometryGrid = uigridlayout(app.RollgeometryPanel);
            app.Heightofrollcenterfronth_RCfLabel = uilabel(app.RollGeometryGrid);
            app.Heightofrollcenterfronth_RCfLabel.Text = '            Height of roll center front h_RCf          ';
            app.Heightofrollcenterrearh_RCrLabel = uilabel(app.RollGeometryGrid);
            app.Heightofrollcenterrearh_RCrLabel.Text = 'Height of roll center rear h_RCr';
            app.mLabel_3 = uilabel(app.RollGeometryGrid);
            app.mLabel_3.Text = 'm';
            app.mLabel_4 = uilabel(app.RollGeometryGrid);
            app.mLabel_4.Text = 'm';
            app.HRollFrontEditField = uieditfield(app.RollGeometryGrid, 'numeric');
            app.HRollFrontEditField.Value = 0.08;
            app.HRollRearEditField = uieditfield(app.RollGeometryGrid, 'numeric');
            app.HRollRearEditField.Value = 0.11;
            app.SpringdamperdataPanel = uipanel(app.SuspensionGrid);
            app.SpringdamperdataPanel.Title = 'Spring / damper data';
            app.SpringDamperGrid = uigridlayout(app.SpringdamperdataPanel);
            app.NmLabel = uilabel(app.SpringDamperGrid);
            app.NmLabel.Text = 'N/m';
            app.NmLabel_2 = uilabel(app.SpringDamperGrid);
            app.NmLabel_2.Text = 'N/m';
            app.NmradLabel = uilabel(app.SpringDamperGrid);
            app.NmradLabel.Text = 'Nm/rad';
            app.NmradLabel_2 = uilabel(app.SpringDamperGrid);
            app.NmradLabel_2.Text = 'Nm/rad';
            app.kgLabel_2 = uilabel(app.SpringDamperGrid);
            app.kgLabel_2.Text = 'kg';
            app.kgLabel = uilabel(app.SpringDamperGrid);
            app.kgLabel.Text = 'kg';
            app.Label_20 = uilabel(app.SpringDamperGrid);
            app.Label_20.Text = '-';
            app.Label_21 = uilabel(app.SpringDamperGrid);
            app.Label_21.Text = '-';
            app.SprungmassfrontLabel = uilabel(app.SpringDamperGrid);
            app.SprungmassfrontLabel.Text = 'Sprung mass front';
            app.SprungmassrearLabel = uilabel(app.SpringDamperGrid);
            app.SprungmassrearLabel.Text = 'Sprung mass rear';
            app.Dampingratiolateralrollzeta_phiLabel = uilabel(app.SpringDamperGrid);
            app.Dampingratiolateralrollzeta_phiLabel.Text = {'Damping ratio lateral/roll '; 'zeta_phi'};
            app.Dampingratiolongitudalpitchzeta_thetaLabel = uilabel(app.SpringDamperGrid);
            app.Dampingratiolongitudalpitchzeta_thetaLabel.Text = {'Damping ratio longitudal/pitch '; 'zeta_theta'};
            app.Whellraterearkw_rLabel = uilabel(app.SpringDamperGrid);
            app.Whellraterearkw_rLabel.Text = 'Whell rate rear kw_r';
            app.AntirollbarstiffnesrearK_ARB_rLabel = uilabel(app.SpringDamperGrid);
            app.AntirollbarstiffnesrearK_ARB_rLabel.Text = {'Anti roll bar stiffnes rear '; 'K_ARB_r'};
            app.Whellratefrontkw_fLabel = uilabel(app.SpringDamperGrid);
            app.Whellratefrontkw_fLabel.Text = 'Whell rate front kw_f';
            app.AntirollbarstiffnesfrontK_ARB_fLabel = uilabel(app.SpringDamperGrid);
            app.AntirollbarstiffnesfrontK_ARB_fLabel.Text = {'Anti roll bar stiffnes front'; 'K_ARB_f'};
            app.WhellRateFrontEditField = uieditfield(app.SpringDamperGrid, 'numeric');
            app.WhellRateRearEditField = uieditfield(app.SpringDamperGrid, 'numeric');
            app.ARBFrontEditField = uieditfield(app.SpringDamperGrid, 'numeric');
            app.ARBRearEditField = uieditfield(app.SpringDamperGrid, 'numeric');
            app.SprungMassRearEditField = uieditfield(app.SpringDamperGrid, 'numeric');
            app.DampingRatioLateralEditField = uieditfield(app.SpringDamperGrid, 'numeric');
            app.SprungMassFrontEditField = uieditfield(app.SpringDamperGrid, 'numeric');
            app.DamingRatioLongitudalEditField = uieditfield(app.SpringDamperGrid, 'numeric');
            app.AntigeometryPanel = uipanel(app.SuspensionGrid);
            app.AntigeometryPanel.Title = 'Anti geometry';
            app.AntiGeometryGrid = uigridlayout(app.AntigeometryPanel);
            app.AntiDiveEditField = uieditfield(app.AntiGeometryGrid, 'numeric');
            app.AntiDiveEditField.Value = 0.25;
            app.AntiSquatEditField = uieditfield(app.AntiGeometryGrid, 'numeric');
            app.AntiSquatEditField.Value = 0.5;
            app.Label_18 = uilabel(app.AntiGeometryGrid);
            app.Label_18.Text = '-';
            app.Label_19 = uilabel(app.AntiGeometryGrid);
            app.Label_19.Text = '-';
            app.AntiDiveADLabel = uilabel(app.AntiGeometryGrid);
            app.AntiDiveADLabel.Text = 'Anti Dive AD';
            app.AntiSquatASLabel = uilabel(app.AntiGeometryGrid);
            app.AntiSquatASLabel.Text = 'Anti Squat AS';
            app.DrivetrainTab = uitab(app.TabGroup);
            app.DrivetrainTab.Title = 'Drivetrain';
            app.DrivetrainGrid = uigridlayout(app.DrivetrainTab);
            app.DrivetrainPanel = uipanel(app.DrivetrainGrid);
            app.DrivetrainPanel.Title = 'Drivetrain parameters';
            app.DrivetrainPanelGrid = uigridlayout(app.DrivetrainPanel);
            app.MaximalbrakerecuperationpowerLabel = uilabel(app.DrivetrainPanelGrid);
            app.MaximalbrakerecuperationpowerLabel.Text = 'Maximal brake/recuperation power';
            app.MaximaldrivepowerLabel = uilabel(app.DrivetrainPanelGrid);
            app.MaximaldrivepowerLabel.Text = 'Maximal drive power';
            app.MaximalbrakerecuperationtorqueLabel = uilabel(app.DrivetrainPanelGrid);
            app.MaximalbrakerecuperationtorqueLabel.Text = 'Maximal brake/recuperation torque';
            app.MaximaldrivetorqueLabel = uilabel(app.DrivetrainPanelGrid);
            app.MaximaldrivetorqueLabel.Text = 'Maximal drive torque';
            app.kWLabel = uilabel(app.DrivetrainPanelGrid);
            app.kWLabel.Text = 'kW';
            app.kWLabel_2 = uilabel(app.DrivetrainPanelGrid);
            app.kWLabel_2.Text = 'kW';
            app.NmLabel_3 = uilabel(app.DrivetrainPanelGrid);
            app.NmLabel_3.Text = 'Nm';
            app.NmLabel_4 = uilabel(app.DrivetrainPanelGrid);
            app.NmLabel_4.Text = 'Nm';
            app.sLabel = uilabel(app.DrivetrainPanelGrid);
            app.sLabel.Text = 's';
            app.MaxBrakeTorqueEditField = uieditfield(app.DrivetrainPanelGrid, 'numeric');
            app.MaxBrakeTorqueEditField.Value = 460;
            app.MaxDriveTorqueEditField = uieditfield(app.DrivetrainPanelGrid, 'numeric');
            app.MaxDriveTorqueEditField.Value = 460;
            app.MaxBrakePowerEditField = uieditfield(app.DrivetrainPanelGrid, 'numeric');
            app.MaxBrakePowerEditField.Value = 80;
            app.MaxDrivePowerEditField = uieditfield(app.DrivetrainPanelGrid, 'numeric');
            app.MaxDrivePowerEditField.Value = 80;
            app.FirstOrderTimeConstantEditField = uieditfield(app.DrivetrainPanelGrid, 'numeric');
            app.FirstOrderTimeConstantEditField.Value = 0.03;
            app.FirstordertimeconstantLabel = uilabel(app.DrivetrainPanelGrid);
            app.FirstordertimeconstantLabel.Text = 'First order time constant';
            app.SteeringSystem = uitab(app.TabGroup);
            app.SteeringSystem.Title = 'Steering';
            app.SteeringGrid = uigridlayout(app.SteeringSystem);
            app.SteeringPanel = uipanel(app.SteeringGrid);
            app.SteeringPanel.Title = 'Steering parametrs';
            app.SteeringPanelGrid = uigridlayout(app.SteeringPanel);
            app.NaturalFrequancyEditField = uieditfield(app.SteeringPanelGrid, 'numeric');
            app.NaturalFrequancyEditField.Value = 15;
            app.DampingRatioEditField = uieditfield(app.SteeringPanelGrid, 'numeric');
            app.DampingRatioEditField.Value = 1;
            app.NaturalfreuancyIIordermodelLabel = uilabel(app.SteeringPanelGrid);
            app.NaturalfreuancyIIordermodelLabel.Text = 'Natural freuancy II order model';
            app.radsLabel = uilabel(app.SteeringPanelGrid);
            app.radsLabel.Text = 'rad/s';
            app.Label_23 = uilabel(app.SteeringPanelGrid);
            app.Label_23.Text = '-';
            app.DampingratioIIordermodelLabel = uilabel(app.SteeringPanelGrid);
            app.DampingratioIIordermodelLabel.Text = 'Damping ratio II order model';
            app.MaxsteeringangleLabel = uilabel(app.SteeringPanelGrid);
            app.MaxsteeringangleLabel.Text = 'Max steering angle';
            app.radLabel = uilabel(app.SteeringPanelGrid);
            app.radLabel.Text = 'rad';
            app.MaxSteerignAngleEditField = uieditfield(app.SteeringPanelGrid, 'numeric');
            app.MaxSteerignAngleEditField.Value = 0.4;
            app.SolverTab = uitab(app.TabGroup);
            app.SolverTab.Title = 'Solver';
            app.SolverGrid = uigridlayout(app.SolverTab);
            app.SolverModePanel = uipanel(app.SolverGrid);
            app.SolverModePanel.Title = 'Solver Mode';
            app.SolverModeGrid = uigridlayout(app.SolverModePanel);
            app.SolvelevelDropDownLabel = uilabel(app.SolverModeGrid);
            app.SolvelevelDropDownLabel.Text = 'Solve level';
            app.SolverLevelDropDown = uidropdown(app.SolverModeGrid);
            app.SolverLevelDropDown.Items = {'Preview', 'Debug', 'Final'};
            app.SolverLevelDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverLevelDropDownValueChanged, true);
            app.SolverLevelDropDown.Value = 'Debug';
            app.BackendmodelDropDownLabel = uilabel(app.SolverModeGrid);
            app.BackendmodelDropDownLabel.Text = 'Backend model';
            app.LTOModeDropDown = uidropdown(app.SolverModeGrid);
            app.LTOModeDropDown.Items = {'Point mass', 'Dynamic bicycle', 'Quasi-static suspension', 'Dynamic suspension'};
            app.LTOModeDropDown.ValueChangedFcn = createCallbackFcn(app, @LTOModeDropDownValueChanged, true);
            app.LTOModeDropDown.Value = 'Point mass';
            app.InitialguessstrategyDropDownLabel = uilabel(app.SolverModeGrid);
            app.InitialguessstrategyDropDownLabel.Text = 'Initial guess strategy';
            app.InitialguessstrategyDropDown = uidropdown(app.SolverModeGrid);
            app.InitialguessstrategyDropDown.Items = {'Auto staged pipeline', 'Direct solve', 'Bacward Forward', 'From file'};
            app.InitialguessstrategyDropDown.Value = 'Auto staged pipeline';
            app.DiscretizationPanel = uipanel(app.SolverGrid);
            app.DiscretizationPanel.Title = 'Discretization';
            app.DiscretizationGrid = uigridlayout(app.DiscretizationPanel);
            app.DebugnumberofpointsLabel = uilabel(app.DiscretizationGrid);
            app.DebugnumberofpointsLabel.Text = 'Debug number of points';
            app.PreviewnumberofpointsLabel = uilabel(app.DiscretizationGrid);
            app.PreviewnumberofpointsLabel.Text = 'Preview number of points';
            app.FinalnumberofpointsLabel = uilabel(app.DiscretizationGrid);
            app.FinalnumberofpointsLabel.Text = 'Final number of points';
            app.DebugNumberPointsEditField = uieditfield(app.DiscretizationGrid, 'numeric');
            app.DebugNumberPointsEditField.Value = 60;
            app.PrewievNumberPointsEditField = uieditfield(app.DiscretizationGrid, 'numeric');
            app.PrewievNumberPointsEditField.Value = 150;
            app.FinalNumberPointsEditField = uieditfield(app.DiscretizationGrid, 'numeric');
            app.FinalNumberPointsEditField.Value = 600;
            app.IntegratorDropDown = uidropdown(app.DiscretizationGrid);
            app.IntegratorDropDown.Items = {'Euler', 'RK4', 'Direct colocation'};
            app.IntegratorDropDown.Value = 'RK4';
            app.IntegratorLabel = uilabel(app.DiscretizationGrid);
            app.IntegratorLabel.Text = 'Integrator';
            app.SubstepsofintegrationLabel = uilabel(app.DiscretizationGrid);
            app.SubstepsofintegrationLabel.Text = 'Substeps of integration';
            app.SubstepsofIntegrationEditField = uieditfield(app.DiscretizationGrid, 'numeric');
            app.InitialguessbackwardforwardsettingsPanel = uipanel(app.SolverGrid);
            app.InitialguessbackwardforwardsettingsPanel.Title = 'Initial guess backward-forward settings';
            app.InitialGuessGrid = uigridlayout(app.InitialguessbackwardforwardsettingsPanel);
            app.Label_25 = uilabel(app.InitialGuessGrid);
            app.Label_25.Text = '';
            app.SafetyfactorforBFLabel = uilabel(app.InitialGuessGrid);
            app.SafetyfactorforBFLabel.Text = 'Safety factor for BF';
            app.MaximalspeedforBFLabel = uilabel(app.InitialGuessGrid);
            app.MaximalspeedforBFLabel.Text = 'Maximal speed for BF';
            app.InitalspeedforBFLabel = uilabel(app.InitialGuessGrid);
            app.InitalspeedforBFLabel.Text = 'Inital speed for BF';
            app.InitalSpeedEditField = uieditfield(app.InitialGuessGrid, 'numeric');
            app.InitalSpeedEditField.Value = 1;
            app.MaximalSpeedEditField = uieditfield(app.InitialGuessGrid, 'numeric');
            app.MaximalSpeedEditField.Value = 25;
            app.SafetyFactoEditField = uieditfield(app.InitialGuessGrid, 'numeric');
            app.SafetyFactoEditField.Value = 0.85;
            app.IPOPTsettingsPanel = uipanel(app.SolverGrid);
            app.IPOPTsettingsPanel.Title = 'IPOPT settings';
            app.IPOPTSettingsPanelGrid = uigridlayout(app.IPOPTsettingsPanel);
            app.ToldebugLabel = uilabel(app.IPOPTSettingsPanelGrid);
            app.ToldebugLabel.Text = 'Tol debug';
            app.TolpreviewLabel = uilabel(app.IPOPTSettingsPanelGrid);
            app.TolpreviewLabel.Text = 'Tol preview';
            app.TolfinalLabel = uilabel(app.IPOPTSettingsPanelGrid);
            app.TolfinalLabel.Text = ' Tol final';
            app.MaxiterationsLabel = uilabel(app.IPOPTSettingsPanelGrid);
            app.MaxiterationsLabel.Text = 'Max iterations';
            app.PrintlevelLabel = uilabel(app.IPOPTSettingsPanelGrid);
            app.PrintlevelLabel.Text = 'Print level';
            app.AcceptabletolLabel = uilabel(app.IPOPTSettingsPanelGrid);
            app.AcceptabletolLabel.Text = 'Acceptable tol';
            app.TolDebugEditField = uieditfield(app.IPOPTSettingsPanelGrid, 'numeric');
            app.TolPreviewEditField = uieditfield(app.IPOPTSettingsPanelGrid, 'numeric');
            app.TolFinalEditField = uieditfield(app.IPOPTSettingsPanelGrid, 'numeric');
            app.MaxIterationsEditField = uieditfield(app.IPOPTSettingsPanelGrid, 'numeric');
            app.PrintLevelEditField = uieditfield(app.IPOPTSettingsPanelGrid, 'numeric');
            app.AcceptableTolEditField = uieditfield(app.IPOPTSettingsPanelGrid, 'numeric');
            app.BoundsTab = uitab(app.TabGroup);
            app.BoundsTab.Title = 'Bounds';
            app.BoundsGrid = uigridlayout(app.BoundsTab);
            app.ControlratesboundsPanel = uipanel(app.BoundsGrid);
            app.ControlratesboundsPanel.Title = 'Control rates bounds';
            app.ControlBoundsGrid = uigridlayout(app.ControlratesboundsPanel);
            app.MaxsteeringcomandrateLabel = uilabel(app.ControlBoundsGrid);
            app.MaxsteeringcomandrateLabel.Text = 'Max steering comand rate';
            app.MaxtorquecommandratenormalizedLabel = uilabel(app.ControlBoundsGrid);
            app.MaxtorquecommandratenormalizedLabel.Text = 'Max torque command rate normalized';
            app.rads2Label = uilabel(app.ControlBoundsGrid);
            app.rads2Label.Text = 'rad/s^2';
            app.Label_26 = uilabel(app.ControlBoundsGrid);
            app.Label_26.Text = '-';
            app.MaxSteeringCommandRateEditField = uieditfield(app.ControlBoundsGrid, 'numeric');
            app.MaxTorqueCommandRateEditField = uieditfield(app.ControlBoundsGrid, 'numeric');
            app.StateboundsPanel = uipanel(app.BoundsGrid);
            app.StateboundsPanel.Title = 'State bounds';
            app.StateBoundsGrid = uigridlayout(app.StateboundsPanel);
            app.radLabel_2 = uilabel(app.StateBoundsGrid);
            app.radLabel_2.Text = 'rad';
            app.msLabel = uilabel(app.StateBoundsGrid);
            app.msLabel.Text = 'm/s';
            app.msLabel_2 = uilabel(app.StateBoundsGrid);
            app.msLabel_2.Text = 'm/s';
            app.mLabel_5 = uilabel(app.StateBoundsGrid);
            app.mLabel_5.Text = 'm';
            app.MinimaltrackmarginLabel = uilabel(app.StateBoundsGrid);
            app.MinimaltrackmarginLabel.Text = 'Minimal track margin';
            app.MinimalspeedLabel = uilabel(app.StateBoundsGrid);
            app.MinimalspeedLabel.Text = 'Minimal speed';
            app.MaximalspeedLabel = uilabel(app.StateBoundsGrid);
            app.MaximalspeedLabel.Text = 'Maximal speed';
            app.MaximalbetaangleLabel = uilabel(app.StateBoundsGrid);
            app.MaximalbetaangleLabel.Text = 'Maximal beta angle';
            app.BetaAngleEditField = uieditfield(app.StateBoundsGrid, 'numeric');
            app.MaximalSpeedBoundEditField = uieditfield(app.StateBoundsGrid, 'numeric');
            app.MinimalSpeedBoundEditField = uieditfield(app.StateBoundsGrid, 'numeric');
            app.MinimalTrackMarginEditField = uieditfield(app.StateBoundsGrid, 'numeric');
            app.TrackWidithDropDown = uidropdown(app.StateBoundsGrid);
            app.TrackWidithDropDown.Items = {'Full track', 'Respecting margin'};
            app.TrackWidithDropDown.Value = 'Full track';
            app.TrackwidithutilizationmodeLabel = uilabel(app.StateBoundsGrid);
            app.TrackwidithutilizationmodeLabel.Text = 'Track widith utilization mode';
            app.CostTab = uitab(app.TabGroup);
            app.CostTab.Title = 'Cost';
            app.CostGrid = uigridlayout(app.CostTab);
            app.StatecostPanel = uipanel(app.CostGrid);
            app.StatecostPanel.Title = 'State cost';
            app.StateCostGrid = uigridlayout(app.StatecostPanel);
            app.BetaCostEditField = uieditfield(app.StateCostGrid, 'numeric');
            app.SpeedCostEditField = uieditfield(app.StateCostGrid, 'numeric');
            app.BetacostLabel = uilabel(app.StateCostGrid);
            app.BetacostLabel.Text = 'Beta cost';
            app.SpeedcostLabel = uilabel(app.StateCostGrid);
            app.SpeedcostLabel.Text = 'Speed cost';
            app.ControlcostPanel = uipanel(app.CostGrid);
            app.ControlcostPanel.Title = 'Control cost';
            app.ControlCostGrid = uigridlayout(app.ControlcostPanel);
            app.SteerRateCostEditField = uieditfield(app.ControlCostGrid, 'numeric');
            app.TorqueRateCostEditField = uieditfield(app.ControlCostGrid, 'numeric');
            app.SteerratecostLabel = uilabel(app.ControlCostGrid);
            app.SteerratecostLabel.Text = 'Steer rate cost';
            app.TorqueratecostLabel = uilabel(app.ControlCostGrid);
            app.TorqueratecostLabel.Text = 'Torque rate cost';
            app.SlackscostPanel = uipanel(app.CostGrid);
            app.SlackscostPanel.Title = 'Slacks cost';
            app.SlacksCostGrid = uigridlayout(app.SlackscostPanel);
            app.PowerSlackCostEditField = uieditfield(app.SlacksCostGrid, 'numeric');
            app.FrictionElipseSlackCostEditField = uieditfield(app.SlacksCostGrid, 'numeric');
            app.TrackViolationSlackCostEditField = uieditfield(app.SlacksCostGrid, 'numeric');
            app.PowerslackcostLabel = uilabel(app.SlacksCostGrid);
            app.PowerslackcostLabel.Text = 'Power slack cost';
            app.FrictionelipseslackcostLabel = uilabel(app.SlacksCostGrid);
            app.FrictionelipseslackcostLabel.Text = 'Friction elipse slack cost';
            app.TrackviolationslackcostLabel = uilabel(app.SlacksCostGrid);
            app.TrackviolationslackcostLabel.Text = 'Track violation slack cost';
            app.FilesTab = uitab(app.TabGroup);
            app.FilesTab.Title = 'Files';
            app.FilesGrid = uigridlayout(app.FilesTab);
            app.TrackfilePanel = uipanel(app.FilesGrid);
            app.TrackfilePanel.Title = 'Track file';
            app.TrackFileGrid = uigridlayout(app.TrackfilePanel);
            app.TrackfileformatRequiredCSVx_left_my_left_mx_center_my_center_mx_right_my_right_mLabel = uilabel(app.TrackFileGrid);
            app.TrackfileformatRequiredCSVx_left_my_left_mx_center_my_center_mx_right_my_right_mLabel.Text = '                                                               Track file format :  Required CSV : x_left_m,y_left_m,x_center_m,y_center_m,x_right_m,y_right_m';
            app.EditField_6Label_2 = uilabel(app.TrackFileGrid);
            app.EditField_6Label_2.Text = 'Track file path';
            app.TrackfilepathEditField = uieditfield(app.TrackFileGrid, 'text');
            app.TrackfilepathEditField.Value = 'tracks/example_track.csv';
            app.PreviewTrackButton = uibutton(app.TrackFileGrid, 'push');
            app.PreviewTrackButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewTrackButtonPushed, true);
            app.PreviewTrackButton.Text = 'Preview loaded track';
            app.BrowseandloadtrackfromfileButton = uibutton(app.TrackFileGrid, 'push');
            app.BrowseandloadtrackfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseandloadtrackfromfileButtonPushed, true);
            app.BrowseandloadtrackfromfileButton.Text = 'Browse and load  track from file';
            app.TracknotloadedyetLabel = uilabel(app.TrackFileGrid);
            app.TracknotloadedyetLabel.Text = 'Track not loaded yet';
            app.IstrackclosedperiodicDropDownLabel = uilabel(app.TrackFileGrid);
            app.IstrackclosedperiodicDropDownLabel.Text = 'Is track closed/periodic';
            app.IstrackclosedperiodicDropDown = uidropdown(app.TrackFileGrid);
            app.IstrackclosedperiodicDropDown.Items = {'true', 'false'};
            app.IstrackclosedperiodicDropDown.Value = 'true';
            app.ConfigPanel = uipanel(app.FilesGrid);
            app.ConfigPanel.Title = 'Configuration file';
            app.ConfigGrid = uigridlayout(app.ConfigPanel);
            app.EditField_6Label_3 = uilabel(app.ConfigGrid);
            app.EditField_6Label_3.Text = 'Configuration file path';
            app.ConfigurationfilepathEditField = uieditfield(app.ConfigGrid, 'text');
            app.SaveconfigtofileButton = uibutton(app.ConfigGrid, 'push');
            app.SaveconfigtofileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigtofileButtonPushed, true);
            app.SaveconfigtofileButton.Text = 'Save config to file';
            app.LoadconfigfromfileButton = uibutton(app.ConfigGrid, 'push');
            app.LoadconfigfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadconfigfromfileButtonPushed, true);
            app.LoadconfigfromfileButton.Text = 'Load config from file';
            app.BrowseconfigurationfilestoloadButton = uibutton(app.ConfigGrid, 'push');
            app.BrowseconfigurationfilestoloadButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseconfigurationfilestoloadButtonPushed, true);
            app.BrowseconfigurationfilestoloadButton.Text = 'Browse configuration files to load';
            app.ConfignotloadedyetLabel = uilabel(app.ConfigGrid);
            app.ConfignotloadedyetLabel.Text = 'Config not loaded yet';
            app.InitialGuessPreloadPanel = uipanel(app.FilesGrid);
            app.InitialGuessPreloadPanel.Title = 'Initial guess / solution file';
            app.InitallGuessPrelodGrid = uigridlayout(app.InitialGuessPreloadPanel);
            app.EditField_6Label_4 = uilabel(app.InitallGuessPrelodGrid);
            app.EditField_6Label_4.Text = 'Initial guess path';
            app.InitialguesspathEditField = uieditfield(app.InitallGuessPrelodGrid, 'text');
            app.BrowseandloadinitalguessfromfileButton = uibutton(app.InitallGuessPrelodGrid, 'push');
            app.BrowseandloadinitalguessfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseandloadinitalguessfromfileButtonPushed, true);
            app.BrowseandloadinitalguessfromfileButton.Text = 'Browse and load inital guess from file';
            app.SaveinitialguesstofileButton = uibutton(app.InitallGuessPrelodGrid, 'push');
            app.SaveinitialguesstofileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinitialguesstofileButtonPushed, true);
            app.SaveinitialguesstofileButton.Text = 'Save initial guess to file ';
            app.OutputPanel = uipanel(app.FilesGrid);
            app.OutputPanel.Title = 'Output';
            app.OutputGrid = uigridlayout(app.OutputPanel);
            app.EditField_6Label_5 = uilabel(app.OutputGrid);
            app.EditField_6Label_5.Text = 'Output folder path';
            app.OutputfolderpathEditField = uieditfield(app.OutputGrid, 'text');
            app.ExportNLPsolutiontoCSVButton = uibutton(app.OutputGrid, 'push');
            app.ExportNLPsolutiontoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNLPsolutiontoCSVButtonPushed, true);
            app.ExportNLPsolutiontoCSVButton.Text = 'Export NLP solution to CSV';
            app.ExportNLPsolutiontoMATButton = uibutton(app.OutputGrid, 'push');
            app.ExportNLPsolutiontoMATButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNLPsolutiontoMATButtonPushed, true);
            app.ExportNLPsolutiontoMATButton.Text = 'Export NLP solution to MAT';
            app.BrowseforfolderandexportButton = uibutton(app.OutputGrid, 'push');
            app.BrowseforfolderandexportButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforfolderandexportButtonPushed, true);
            app.BrowseforfolderandexportButton.Text = 'Browse  for folder and export ';
            app.SettingsofoutputexportDropDownLabel = uilabel(app.OutputGrid);
            app.SettingsofoutputexportDropDownLabel.Text = 'Settings of output export';
            app.SettingsofoutputexportDropDown = uidropdown(app.OutputGrid);
            app.SettingsofoutputexportDropDown.Items = {'Only NLP solution', 'NLP solution + config', 'NLP solution + config + track', 'NLP solution + config + plots + track', 'NLP solution + config + initial guess + plots + track', 'NLP solution + config + initial guess + plots + bacward forward + track'};
            app.SettingsofoutputexportDropDown.Value = 'Only NLP solution';
            app.ResultsPanel = uipanel(app.GridLayout);
            app.ResultsPanel.Title = 'Results';
            app.ResultsGrid = uigridlayout(app.ResultsPanel);
            app.PlotcontrolsPanel = uipanel(app.ResultsGrid);
            app.PlotcontrolsPanel.Title = ' Plot controls';
            app.PlotControlsGrid = uigridlayout(app.PlotcontrolsPanel);
            app.PlottypeselectionDropDownLabel = uilabel(app.PlotControlsGrid);
            app.PlottypeselectionDropDownLabel.Text = 'Plot type selection';
            app.PlottypeselectionDropDown = uidropdown(app.PlotControlsGrid);
            app.PlottypeselectionDropDown.Items = {'Trajectory colored by speed', 'Speed profile', 'GG plot', 'Friction usage', 'Controls', 'Command rates', 'Torque / moment', 'Beta angle', 'Slip angles', 'ey / epsi'};
            app.PlottypeselectionDropDown.Value = 'Trajectory colored by speed';
            app.GenerateselectedplotButton = uibutton(app.PlotControlsGrid, 'push');
            app.GenerateselectedplotButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateselectedplotButtonPushed, true);
            app.GenerateselectedplotButton.Text = 'Generate selected plot';
            app.PlotmodeselectionDropDown = uidropdown(app.PlotControlsGrid);
            app.PlotmodeselectionDropDown.Items = {'NLP solution', 'Initial guess'};
            app.PlotmodeselectionDropDown.Value = 'NLP solution';
            app.PlotareaPanel = uipanel(app.ResultsGrid);
            app.PlotareaPanel.Title = 'Plot area';
            app.PlotAreaGrid = uigridlayout(app.PlotareaPanel);
            app.UIAxes = uiaxes(app.PlotAreaGrid);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SummarytablePanel = uipanel(app.ResultsGrid);
            app.SummarytablePanel.Title = 'Summary table';
            app.SummaryTableGrid = uigridlayout(app.SummarytablePanel);
            app.UITable = uitable(app.SummaryTableGrid);
            app.UITable.ColumnName = {'Failed'; 'Solver time'; 'Lap time'};
            app.UITable.RowName = {};
            app.LogDebugPanel = uipanel(app.GridLayout);
            app.LogDebugPanel.Title = 'Log/Debug';
            app.LogDebugGrid = uigridlayout(app.LogDebugPanel);
            app.ConsoleoutputTextAreaLabel = uilabel(app.LogDebugGrid);
            app.ConsoleoutputTextAreaLabel.Text = 'Console output';
            app.ConsoleoutputTextArea = uitextarea(app.LogDebugGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LTO_tunner_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end